classdef Grounds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                 matlab.ui.Figure
        menu                       matlab.ui.container.Toolbar
        menu_pan                   matlab.ui.container.toolbar.ToggleTool
        menu_zoomin                matlab.ui.container.toolbar.ToggleTool
        menu_zoomout               matlab.ui.container.toolbar.ToggleTool
        menu_lockaxis              matlab.ui.container.toolbar.ToggleTool
        menu_tooltip               matlab.ui.container.toolbar.ToggleTool
        menu_help                  matlab.ui.container.toolbar.PushTool
        panel_echo                 matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        txt_out                    matlab.ui.control.TextArea
        panel_actions              matlab.ui.container.Panel
        pb_exit                    matlab.ui.control.Button
        pb_reset                   matlab.ui.control.Button
        pb_exportEcho              matlab.ui.control.Button
        pb_analyzeChange           matlab.ui.control.Button
        pb_compStats               matlab.ui.control.Button
        panel_LOD                  matlab.ui.container.Panel
        pb_dispLOD                 matlab.ui.control.Button
        txt_calcLOD                matlab.ui.control.Label
        pb_calcLOD                 matlab.ui.control.Button
        edt_multFactor             matlab.ui.control.EditField
        txt_multFactor             matlab.ui.control.Label
        rbg_unctype                matlab.ui.container.ButtonGroup
        rb2_varUNC                 matlab.ui.control.RadioButton
        rb1_fixedUNC               matlab.ui.control.RadioButton
        edt_fixedUnc               matlab.ui.control.EditField
        panel_colorscale           matlab.ui.container.Panel
        pb_resetcolorscale         matlab.ui.control.Button
        edt_maxcol                 matlab.ui.control.EditField
        text_maxcol                matlab.ui.control.Label
        edt_mincol                 matlab.ui.control.EditField
        text_mincol                matlab.ui.control.Label
        panel_polygon              matlab.ui.container.ButtonGroup
        pb_cliptopoly              matlab.ui.control.Button
        pb_clearpoly               matlab.ui.control.Button
        pb_loadpoly                matlab.ui.control.Button
        pb_drawpoly                matlab.ui.control.Button
        panel_inputUNC             matlab.ui.container.Panel
        pb_coregister_DOD_and_DPU  matlab.ui.control.Button
        pb_display_DPU             matlab.ui.control.Button
        pb_calculate_DPU           matlab.ui.control.Button
        pb_coregister_UNCs         matlab.ui.control.Button
        pb_display_UNC2            matlab.ui.control.Button
        text_UNC2                  matlab.ui.control.Label
        pb_openUN2                 matlab.ui.control.Button
        pb_display_UNC1            matlab.ui.control.Button
        text_UNC1                  matlab.ui.control.Label
        pb_openUN1                 matlab.ui.control.Button
        panel_inputDEM             matlab.ui.container.Panel
        pb_display_DOD             matlab.ui.control.Button
        pb_calculate_DOD           matlab.ui.control.Button
        pb_coregister_DEMs         matlab.ui.control.Button
        pb_display_DEM2            matlab.ui.control.Button
        text_DEM2                  matlab.ui.control.Label
        pb_openDEM2                matlab.ui.control.Button
        pb_display_DEM1            matlab.ui.control.Button
        text_DEM1                  matlab.ui.control.Label
        pb_openDEM1                matlab.ui.control.Button
        main_axes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        % general properties (static)
        Name = 'Grounds'; % app name
        Version = '2.0.0-beta'; % app version
        CoffeeVersion = '2.0.0-alpha.3'; % coffee version app was built with, from CFF_coffee_version()
        
        % files and folders, set in startup function
        RootFolder = ''; % root folder of app
        UserFolder = ''; % user folder for app
        ConfigFile = ''; % app config file (json format)
        
        % some data to save
        lastDataFolder; % last data folder opened
        ROI; % polygon region of interest
        axisLims; % axis lims when locking the view
        data_currently_displayed; % data type currently displayed
        
        % and then, the data itself
        DEM1;
        DEM1_X;
        DEM1_Y;
        DEM1_cmin;
        DEM1_cmax;
        DEM1_file;
        
        DEM2;
        DEM2_X;
        DEM2_Y;
        DEM2_cmin;
        DEM2_cmax;
        DEM2_file;
        
        UNC1;
        UNC1_X;
        UNC1_Y;
        UNC1_cmin;
        UNC1_cmax;
        UNC1_file;
        
        UNC2;
        UNC2_X;
        UNC2_Y;
        UNC2_cmin;
        UNC2_cmax;
        UNC2_file;

        DOD;
        DOD_X;
        DOD_Y;
        DOD_cmin;
        DOD_cmax;
        
        DPU;
        DPU_X;
        DPU_Y;
        DPU_cmin;
        DPU_cmax;
        
        LOD;
        LOD_X;
        LOD_Y;
        LOD_cmin;
        LOD_cmax;

    end
    
    methods (Access = private)
        
        % Initialize app's config file
        function init_config_file(app)
            if ~isfolder(fileparts(app.ConfigFile))
                % create folder
                mkdir(fileparts(app.ConfigFile));
            end
            if isfile(app.ConfigFile)
                % delete existing file
                delete(app.ConfigFile);
            end
            % initialize blank config file
            fid = fopen(app.ConfigFile,'w');
            fclose(fid);
        end
        
        % Get a field value from app's config file
        function out = get_config_field(app,fieldName)
            % init fail output
            out = '';
            if isfile(app.ConfigFile)
                % read config file
                configJSON = fileread(app.ConfigFile);
                if ~isempty(configJSON)
                    % decode config file
                    config = jsondecode(configJSON);
                    if isfield(config,fieldName)
                        % read field
                        out = config.(fieldName);
                    end
                end
            end
        end
        
        % Set a field value in app's config file
        function out = set_config_field(app,fieldName,fieldVal)
            % init fail output
            out = false;
            if isfile(app.ConfigFile)
                % read config file
                configJSON = fileread(app.ConfigFile);
                if ~isempty(configJSON)
                    % decode config file
                    config = jsondecode(fileread(app.ConfigFile));
                else
                    % inititate blank config
                    config = struct();
                end
                % set value
                config.(fieldName) = fieldVal;
                % encode config
                configJSON = jsonencode(config);
                % rewrite config file
                fid = fopen(app.ConfigFile,'w');
                fprintf(fid,'%c',configJSON);
                fclose(fid);
                % success
                out = true;
            end
        end
        
        % Check if a folder contains the CoFFee toolbox
        function isCoffeeFolder = is_coffee_folder(~,folder)
            % for now, we just test the existence of a folder and of the
            % coffee version function
            if isfolder(folder) && exist(fullfile(folder,'CFF_coffee_version.m'),'file')
                isCoffeeFolder = true;
            else
                isCoffeeFolder = false;
            end
        end
        
        % get the CoFFee version from its folder
        function coffeeVer = get_coffee_version(app,folder)
            % first, check that it is a coffee folder
            if ~is_coffee_folder(app,folder)
                coffeeVer = NaN;
                return
            end
            % get version of that coffee
            curdir = cd;
            cd(folder);
            coffeeVer = CFF_coffee_version();
            cd(curdir);
        end
        
        % Check if a CoFFee folder has the expected version
        function isVersionOK = is_coffee_version(app,folder,coffeeVerWanted)
            % first, check that it is a coffee folder
            if ~is_coffee_folder(app,folder)
                isVersionOK = NaN;
                return
            end
            % next, get version of that coffee
            coffeeVerActual = get_coffee_version(app,folder);
            % finally, comapre to expected version
            if strcmp(coffeeVerActual,coffeeVerWanted)
                isVersionOK = true;
            else
                isVersionOK = false;
            end
        end
%         
%         function edit_DOD_CreateFcn(app, hObject, eventdata, handles)
%             % hObject    handle to edit_DOD (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    empty - handles not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function edt_dem1_dir_CreateFcn(app, hObject, eventdata, handles)
%             % hObject    handle to edt_dem1_dir (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    empty - handles not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function edt_dem2_dir_CreateFcn(app, hObject, eventdata, handles)
%             % hObject    handle to edt_dem2_dir (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    empty - handles not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function edt_un1_dir_CreateFcn(app, hObject, eventdata, handles)
%             % hObject    handle to edt_un1_dir (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    empty - handles not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function edt_un2_dir_CreateFcn(app, hObject, eventdata, handles)
%             % hObject    handle to edt_un2_dir (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    empty - handles not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
            
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.menu_pan, app.menu_zoomin, app.menu_zoomout, app.menu_tooltip];
            interactiveTools(event.Source == interactiveTools) = [];
            
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
            
            % Set figure interactions to 'off'.
            datacursormode(app.MainFigure, 'off')
            rotate3d(app.MainFigure, 'off');
            pan(app.MainFigure, 'off');
            zoom(app.MainFigure,'off');
        end
        
        % display requested data
        function results = display_data(app,strData)
            
            % if polygon exists, save it before changing the display
            if ~isempty(app.ROI)
                pos = getPosition(app.ROI);
            end
            
            switch strData
                case 'DEM1'
                    x = app.DEM1_X(1,:);
                    y = app.DEM1_Y(:,1);
                    M = app.DEM1;
                    c(1) = app.DEM1_cmin;
                    c(2) = app.DEM1_cmax;
                case 'DEM2'
                    x = app.DEM2_X(1,:);
                    y = app.DEM2_Y(:,1);
                    M = app.DEM2;
                    c(1) = app.DEM2_cmin;
                    c(2) = app.DEM2_cmax;
                case 'UNC1'
                    x = app.UNC1_X(1,:);
                    y = app.UNC1_Y(:,1);
                    M = app.UNC1;
                    c(1) = app.UNC1_cmin;
                    c(2) = app.UNC1_cmax;
                case 'UNC2'
                    x = app.UNC2_X(1,:);
                    y = app.UNC2_Y(:,1);
                    M = app.UNC2;
                    c(1) = app.UNC2_cmin;
                    c(2) = app.UNC2_cmax;
                case 'DOD'
                    x = app.DOD_X(1,:);
                    y = app.DOD_Y(:,1);
                    M = app.DOD;
                    c(1) = app.DOD_cmin;
                    c(2) = app.DOD_cmax;
                case 'DPU'
                    x = app.DPU_X(1,:);
                    y = app.DPU_Y(:,1);
                    M = app.DPU;
                    c(1) = app.DPU_cmin;
                    c(2) = app.DPU_cmax;
                case 'LOD'
                    x = app.LOD_X(1,:);
                    y = app.LOD_Y(:,1);
                    M = app.LOD;
                    c(1) = app.LOD_cmin;
                    c(2) = app.LOD_cmax;
            end
            
            % record which data is currently on display
            app.data_currently_displayed = strData;
            
            % display data
            cla(app.main_axes);
            imagesc(app.main_axes,x,y,M);
            alpha(double(~isnan(M)));
            axis xy
            grid on
            colorbar
            caxis(c);
            
            % add title and X and Y labels
            title(app.data_currently_displayed);
            set(gca, 'XTickLabel', num2str(get(gca, 'XTick')'));
            set(gca,'XTickLabelRotation',45);
            set(gca, 'YTickLabel', num2str(get(gca, 'YTick')'));
            
            % adjust axis limits
            if ~isempty(app.axisLims)
                % if axes are locked
                axis(app.axisLims);
            else
                % if axes unlocked
                % zoom out
                app.zoomAllTheWayOut();
            end

            % if polygon existed, re-create it now
            if exist('pos','var')
                app.ROI = impoly(gca,pos);
            end
            
            drawnow;

            % enable and update caxis edit boxes
            set(app.edt_mincol,'Value',num2str(c(1)),'Enable','on');
            set(app.edt_maxcol,'Value',num2str(c(2)),'Enable','on');
            set(app.pb_resetcolorscale,'Enable','on');
            
            % enable drawpoly and select poly
            set(app.pb_drawpoly,'Enable','on');
            set(app.pb_loadpoly,'Enable','on');
            
            % enable the Compute Stats (which applies to data displayed)
            set(app.pb_compStats,'Enable','on');
            
        end
        
        function equalizeMapView(app, bufferRatio)
            % This function adjusts the xlim and ylim properties of the map
            % to ensure the map maintains equal units of m/pixel in X and Y
            % so the data never looks stretched.
            % This function is to be called when any of those properties
            % are changed, whether automatically (e.g. when loading new
            % data) or interactively (e.g. using zoom functions).
            
            % these are the current extents of the data view, in
            % easting/northing (meters)
            xlim = get(app.main_axes,'XLim');
            ylim = get(app.main_axes,'YLim');
            
            % The adjustment depends on how the "aspect ratio" (ratio of
            % height to width) of the data view compare to that
            % of the map frame.
            % If the map frame is "flatter" than the data view, we will fit
            % the height of the data view to the map frame and then adjust
            % automatically in width.
            % If the data view is "flatter" than the map frame, we will fit
            % the width of the data view to the map frame and then adjust
            % automatically in height.
            
            % aspect ratio of the data view
            reqAspectRatio = diff(ylim)/diff(xlim);
            
            % aspect ratio of the map frame
            pos = getpixelposition(app.main_axes);
            mapAspectRatio = pos(4)/pos(3);
            
            % Instead of fitting the data view to the data frame exactly,
            % we allow in input some extra buffer. e.g. a value of 1.2
            % would mean that the frame width will represent 120% of the
            % data view width (if fitting in width). This way we "zoom-out"
            % a bit more compare to the original view. By default, we fit
            % exactly using a ratio of 1.
            if ~exist('bufferRatio','var')
                bufferRatio = 1;
            end
            
            if reqAspectRatio > mapAspectRatio
                % map frame is "flatter" than data view
                % -> Define ylim to fit the data in height
                ylim_new = [-diff(ylim),diff(ylim)]*bufferRatio/2 + ylim(1) + diff(ylim)/2;
                % X distance to ensure equal m per pixel in X and Y
                dx = diff(ylim_new)/mapAspectRatio;
                % define xlim with old view centeredd
                xlim_new = [-dx/2,dx/2] + xlim(1) + diff(xlim)/2;
            else
                % data view is "flatter" than map frame
                % -> Define xlim to fit the data in width
                xlim_new = [-diff(xlim),diff(xlim)]*bufferRatio/2 + xlim(1) + diff(xlim)/2;
                % Y distance to ensure equal m per pixel in X and Y
                dy = diff(xlim_new)*mapAspectRatio;
                % define ylim with old view centered
                ylim_new = [-dy/2,dy/2] + ylim(1) + diff(ylim)/2;
            end
            
            % set those new values to map
            set(app.main_axes,'YLim',ylim_new,'XLim',xlim_new);
            drawnow;
        end
        
        
        function zoomAllTheWayOut(app)
            % ZOOMALLTHEWAYOUT   Zoom out to include all objects in map view
            
            % get all graphical objects
            h = allchild(app.main_axes);
            % initialize view limits
            xlim = [nan nan];
            ylim = [nan nan];
            % find view limits that include all objects
            for ii = 1:numel(h)
                if isprop(h(ii),'XData')
                    xlim(1) = min( xlim(1), min(h(ii).XData));
                    xlim(2) = max( xlim(2), max(h(ii).XData));
                    ylim(1) = min( ylim(1), min(h(ii).YData));
                    ylim(2) = max( ylim(2), max(h(ii).YData));
                elseif isprop(h(ii),'Position')
                    xlim(1) = min( xlim(1), min(h(ii).Position(:,1)));
                    xlim(2) = max( xlim(2), max(h(ii).Position(:,1)));
                    ylim(1) = min( ylim(1), min(h(ii).Position(:,2)));
                    ylim(2) = max( ylim(2), max(h(ii).Position(:,2)));
                end
            end
            % default limits where there are no objects in the map
            if any(isnan([xlim,ylim]))
                xlim = [0 1];
                ylim = [0 1];
            end
            % Set map view
            set(app.main_axes,'YLim',ylim,'XLim',xlim);
            % Equalize map view, with a buffer
            app.equalizeMapView(1.2);
            
            drawnow;
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            %% start-up
            
            % Starting message
            txt = sprintf('%s - Starting %s v%s (powered by CoFFee v%s). Please wait...',datestr(now),app.Name,app.Version,app.CoffeeVersion);
            set(app.txt_out,'Value',{txt});
            
            % If on MATLAB, start with adding the app's root folder (and
            % its subfolders) to the path
            app.RootFolder = fileparts(mfilename('fullpath'));
            if ~isdeployed
                addpath(genpath(app.RootFolder));
            end
            
            % Set (and create if needed) the app user folder
            app.UserFolder = regexprep(userpath,'MATLAB',app.Name);
            if ~isfolder(app.UserFolder)
                mkdir(app.UserFolder);
            end
            
            % Init config file if needed
            app.ConfigFile = fullfile(app.UserFolder,'config.json');
            if ~isfile(app.ConfigFile)
                init_config_file(app);
            end
            
            % If on MATLAB, need to find and add CoFFee to the path
            if ~isdeployed
                % check coffee folder
                coffeeFolder = get_config_field(app,'coffeeFolder');
                if ~is_coffee_folder(app,coffeeFolder)
                    % throw an alert to find manually a suitable coffee folder
                    coffeeFolder = uigetdir(app.RootFolder,'Select suitable CoFFee folder');
                    figure(app.MainFigure); % uigetdir sometimes puts app in the background. Bring it back on top
                    % second check
                    if ~is_coffee_folder(app,coffeeFolder)
                        error('Not a CoFFee folder.');
                    end
                end
                % check coffee version is the one we need
                isVersionOK = is_coffee_version(app,coffeeFolder,app.CoffeeVersion);
                if ~isVersionOK
                    warning(['This version of %s (%s) was built with a version'...
                        ' of CoFFee (%s) that is different from that (%s) of the toolbox'...
                        ' you have specified (%s). Proceeding, but you may experience'...
                        ' issues. Consider checking out the correct CoFFee version.'],...
                        app.Name,app.Version,app.CoffeeVersion,get_coffee_version(app,coffeeFolder),coffeeFolder);
                end
                % add coffee to path
                addpath(genpath(coffeeFolder));
                % save that path in config file
                set_config_field(app,'coffeeFolder',coffeeFolder);
            end

            % Add a post-zoom callback to equalize map axes
            h = zoom(app.MainFigure);
            h.ActionPostCallback = @(o,e) app.equalizeMapView();
            
        end

        % Button pushed function: pb_openDEM1
        function pb_openDEM1_Callback(app, event)
            
            if ~isempty(app.lastDataFolder)
                trythispath = app.lastDataFolder;
            else
                trythispath = app.RootFolder;
            end
            
            [file_name, path_name] = uigetfile({...
                '*.asc','ASCII grid files (*.asc)'; ...
                '*.txt','Delimited grid files (*.txt)'; ...
                '*.tif;*.tiff','GeoTIF files (*.tif,*.tiff)' }, ...
                'select file for DEM 1',...
                trythispath);
            
            if isequal(file_name,0)
                msgbox('Please try again!','Error!','Modal');
                errordlg('File not found','File Error');
                return;
            end
            
            app.DEM1_file = fullfile(path_name,file_name);
            
            switch file_name(end-3:end)
                case '.asc'
                    [app.DEM1,app.DEM1_X,app.DEM1_Y] = CFF_read_asc(app.DEM1_file);
                case '.txt'
                    [app.DEM1,app.DEM1_X,app.DEM1_Y] = CFF_read_dlm_grid(app.DEM1_file);
                case {'.tif','tiff'}
                    [app.DEM1,app.DEM1_X,app.DEM1_Y] = CFF_read_tif(app.DEM1_file);
            end
            
            % save
            app.DEM1_cmin = min(app.DEM1(:));
            app.DEM1_cmax = max(app.DEM1(:));

            app.lastDataFolder = path_name;
            
            % update file name and enable on gui
            set(app.text_DEM1,'Text',file_name);
            set(app.text_DEM1,'Enable','on');
            
            % enable display push button
            set(app.pb_display_DEM1,'Enable','on')
            
            % enable coregister button
            if ~isempty(app.DEM1) && ~isempty(app.DEM2)
                set(app.pb_coregister_DEMs,'Enable','on');
            end
            
            % force a display
            app.display_data('DEM1');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Opened DEM 1: %s',regexprep(app.DEM1_file,'\','\\'));
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_display_DEM1
        function pb_display_DEM1_Callback(app, event)
            app.display_data('DEM1');
        end

        % Button pushed function: pb_openDEM2
        function pb_openDEM2_Callback(app, event)
            
            if ~isempty(app.lastDataFolder)
                trythispath = app.lastDataFolder;
            else
                trythispath = app.RootFolder;
            end
            
            [file_name, path_name] = uigetfile({...
                '*.asc','ASCII grid files (*.asc)'; ...
                '*.txt','Delimited grid files (*.txt)'; ...
                '*.tif;*.tiff','GeoTIF files (*.tif,*.tiff)' }, ...
                'select file for DEM 2',...
                trythispath);
            
            if isequal(file_name,0)
                msgbox('Please try again!','Error!','Modal');
                errordlg('File not found','File Error');
                return;
            end
            
            app.DEM2_file = [path_name file_name];
            switch file_name(end-3:end)
                case '.asc'
                    [app.DEM2,app.DEM2_X,app.DEM2_Y] = CFF_read_asc(app.DEM2_file);
                case '.txt'
                    [app.DEM2,app.DEM2_X,app.DEM2_Y] = CFF_read_dlm_grid(app.DEM2_file);
                case {'.tif','tiff'}
                    [app.DEM2,app.DEM2_X,app.DEM2_Y] = CFF_read_tif(app.DEM2_file);
            end
            
            % save
            app.DEM2_cmin = min(app.DEM2(:));
            app.DEM2_cmax = max(app.DEM2(:));

            app.lastDataFolder = path_name;
            
            % update file name and enable on gui
            set(app.text_DEM2,'Text',file_name);
            set(app.text_DEM2,'Enable','on');
            
            % enable display push button
            set(app.pb_display_DEM2,'Enable','on')
            
            % enable coregister button
            if ~isempty(app.DEM1) && ~isempty(app.DEM2)
                set(app.pb_coregister_DEMs,'Enable','on');
            end
            
            % force a display
            app.display_data('DEM2');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Opened DEM 2: %s',regexprep(app.DEM2_file,'\','\\'));
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_display_DEM2
        function pb_display_DEM2_Callback(app, event)
            app.display_data('DEM2');
        end

        % Button pushed function: pb_coregister_DEMs
        function pb_coregister_DEMs_Callback(app, event)
            
            % coregister grids
            [app.DEM1,app.DEM2,X,Y] = CFF_coregister_rasters(...
                app.DEM1,app.DEM1_X,app.DEM1_Y,...
                app.DEM2,app.DEM2_X,app.DEM2_Y);
            
            % update data Xs and Ys
            app.DEM1_X = X;
            app.DEM1_Y = Y;
            app.DEM2_X = X;
            app.DEM2_Y = Y;
            
            % update mins and maxs
            app.DEM1_cmin = min(app.DEM1(:));
            app.DEM1_cmax = max(app.DEM1(:));
            app.DEM2_cmin = min(app.DEM2(:));
            app.DEM2_cmax = max(app.DEM2(:));
            
            % enable calculate DOD push button
            set(app.pb_calculate_DOD,'Enable','on');
            
            % force display update
            app.display_data(app.data_currently_displayed);
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Coregistered DEMs');
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_calculate_DOD
        function pb_calculate_DOD_Callback(app, event)
            
            % create dod from grids
            app.DOD = CFF_calculate_DOD(app.DEM1,app.DEM2);
            
            % save ancil data
            app.DOD_X = app.DEM1_X;
            app.DOD_Y = app.DEM1_Y;
            app.DOD_cmin = min(app.DOD(:));
            app.DOD_cmax = max(app.DOD(:));
            
            % enable DOD display push button
            set(app.pb_display_DOD,'Enable','on');
            
            % with a DOD, we can do a fixed uncertainty LOD, so enable all
            % of those
            set(app.panel_LOD,'Enable','on');
            set(app.txt_multFactor,'Enable','on');
            set(app.edt_multFactor,'Enable','on');
            set(app.rb1_fixedUNC,'Enable','on');
            set(app.edt_fixedUnc,'Enable','on');
            set(app.pb_calcLOD,'Enable','on');
            
            % enable DOD&DPU coregister button
            if ~isempty(app.DOD) && ~isempty(app.DPU)
                set(app.pb_coregister_DOD_and_DPU,'Enable','on');
            end
            
            % enable volume calculation if we have DOD and LOD
            if ~isempty(app.DOD) && ~isempty(app.LOD)
                set(app.pb_vol,'Enable','on');
            end
            
            % force a display
            app.display_data('DOD');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Calculated difference between DEMs (DOD)');
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_display_DOD
        function pb_display_DOD_Callback(app, event)
            app.display_data('DOD');
        end

        % Button pushed function: pb_openUN1
        function pb_openUN1_Callback(app, event)
            
            if ~isempty(app.lastDataFolder)
                trythispath = app.lastDataFolder;
            else
                trythispath = app.RootFolder;
            end
            
            [file_name, path_name] = uigetfile({...
                '*.asc','ASCII grid files (*.asc)'; ...
                '*.txt','Delimited grid files (*.txt)'; ...
                '*.tif;*.tiff','GeoTIF files (*.tif,*.tiff)' }, ...
                'select file for UNC 1',...
                trythispath);
            
            if isequal(file_name,0)
                msgbox('Please try again!','Error!','Modal');
                errordlg('File not found','File Error');
                return;
            end
            
            app.UNC1_file = fullfile(path_name,file_name);
            switch file_name(end-3:end)
                case '.asc'
                    [app.UNC1,app.UNC1_X,app.UNC1_Y] = CFF_read_asc(app.UNC1_file);
                case '.txt'
                    [app.UNC1,app.UNC1_X,app.UNC1_Y] = CFF_read_dlm_grid(app.UNC1_file);
                case {'.tif','tiff'}
                    [app.UNC1,app.UNC1_X,app.UNC1_Y] = CFF_read_tif(app.UNC1_file);
            end
            
            % save
            app.UNC1_cmin = min(app.UNC1(:));
            app.UNC1_cmax = max(app.UNC1(:));

            app.lastDataFolder = path_name;
            
            % update file name and enable on gui
            set(app.text_UNC1,'Text',file_name);
            set(app.text_UNC1,'Enable','on');
            
            % enable display push button
            set(app.pb_display_UNC1,'Enable','on')
            
            % enable coregister button
            if ~isempty(app.UNC1) && ~isempty(app.UNC2)
                set(app.pb_coregister_UNCs,'Enable','on');
            end
            
            % force a display
            app.display_data('UNC1');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Opened spatially-variable uncertainty file 1 (UNC 1): %s',regexprep(app.UNC1_file,'\','\\'));
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_display_UNC1
        function pb_display_UNC1_Callback(app, event)
            app.display_data('UNC1');
        end

        % Button pushed function: pb_openUN2
        function pb_openUN2_Callback(app, event)
            
            if ~isempty(app.lastDataFolder)
                trythispath = app.lastDataFolder;
            else
                trythispath = app.RootFolder;
            end
            
            [file_name, path_name] = uigetfile({...
                '*.asc','ASCII grid files (*.asc)'; ...
                '*.txt','Delimited grid files (*.txt)'; ...
                '*.tif;*.tiff','GeoTIF files (*.tif,*.tiff)' }, ...
                'select file for UNC 2',...
                trythispath);
            
            if isequal(file_name,0)
                msgbox('Please try again!','Error!','Modal');
                errordlg('File not found','File Error');
                return;
            end
            
            app.UNC2_file = fullfile(path_name,file_name);
            switch file_name(end-3:end)
                case '.asc'
                    [app.UNC2,app.UNC2_X,app.UNC2_Y] = CFF_read_asc(app.UNC2_file);
                case '.txt'
                    [app.UNC2_X,app.UNC2_X,app.UNC2_Y] = CFF_read_dlm_grid(app.UNC2_file);
                case {'.tif','tiff'}
                    [app.UNC2,app.UNC2_X,app.UNC2_Y] = CFF_read_tif(app.UNC2_file);
            end
            
            % save
            app.UNC2_cmin = min(app.UNC2(:));
            app.UNC2_cmax = max(app.UNC2(:));
            
            app.lastDataFolder = path_name;
            
            % update file name and enable on gui
            set(app.text_UNC2,'Text',file_name);
            set(app.text_UNC2,'Enable','on');
            
            % enable display push button
            set(app.pb_display_UNC2,'Enable','on')
            
            % enable coregister button
            if ~isempty(app.UNC1) && ~isempty(app.UNC2)
                set(app.pb_coregister_UNCs,'Enable','on');
            end
            
            % force a display
            app.display_data('UNC2');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Opened spatially-variable uncertainty file 2 (UNC 2): %s',regexprep(app.UNC2_file,'\','\\'));
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_display_UNC2
        function pb_display_UNC2_Callback(app, event)
            app.display_data('UNC2');
        end

        % Button pushed function: pb_coregister_UNCs
        function pb_coregister_UNCs_Callback(app, event)
            
            % coregister grids
            [app.UNC1,app.UNC2,X,Y] = CFF_coregister_rasters(...
                app.UNC1,app.UNC1_X,app.UNC1_Y,...
                app.UNC2,app.UNC2_X,app.UNC2_Y);
            
            % update data Xs and Ys
            app.UNC1_X = X;
            app.UNC1_Y = Y;
            app.UNC2_X = X;
            app.UNC2_Y = Y;
            
            % update mins and maxs
            app.UNC1_cmin = min(app.UNC1(:));
            app.UNC1_cmax = max(app.UNC1(:));
            app.UNC2_cmin = min(app.UNC2(:));
            app.UNC2_cmax = max(app.UNC2(:));
            
            % enable calculate DOD push button
            set(app.pb_calculate_DPU,'Enable','on');
            
            % force display update
            app.display_data(app.data_currently_displayed);
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Coregistered uncertainty rasters');
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_calculate_DPU
        function pb_calculate_DPU_Callback(app, event)
            
            % create DPU from grids
            app.DPU = CFF_calculate_DPU(app.UNC1,app.UNC2);
            
            % save ancil data
            app.DPU_X = app.UNC1_X;
            app.DPU_Y = app.UNC1_Y;
            app.DPU_cmin = min(app.DPU(:));
            app.DPU_cmax = max(app.DPU(:));
            
            % enable DPU display push button
            set(app.pb_display_DPU,'Enable','on')
            
            % with a DPU, we can do a variable uncertainty LOD, so enable
            % it
            set(app.panel_LOD,'Enable','on');
            set(app.txt_multFactor,'Enable','on');
            set(app.edt_multFactor,'Enable','on');
            set(app.rb2_varUNC,'Enable','on');
            set(app.pb_calcLOD,'Enable','on');
            
            % enable DOD&DPU coregister button
            if ~isempty(app.DOD) && ~isempty(app.DPU)
                set(app.pb_coregister_DOD_and_DPU,'Enable','on');
            end
            
            % enable choice in LOD
            set(app.rbg_unctype,'Enable','on');
            
            % force a display
            app.display_data('DPU');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Calculated combined uncertainty raster (DPU)');
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_display_DPU
        function pb_display_DPU_Callback(app, event)
            app.display_data('DPU');
        end

        % Button pushed function: pb_coregister_DOD_and_DPU
        function pb_coregister_DOD_and_DPU_Callback(app, event)
            
            % coregister grids
            [app.DOD,app.DPU,X,Y] = CFF_coregister_rasters(...
                app.DOD,app.DOD_X,app.DOD_Y,...
                app.DPU,app.DPU_X,app.DPU_Y);
            
            % update data Xs and Ys
            app.DOD_X = X;
            app.DOD_Y = Y;
            app.DPU_X = X;
            app.DPU_Y = Y;
            
            % update mins and maxs
            app.DOD_cmin = min(app.DOD(:));
            app.DOD_cmax = max(app.DOD(:));
            app.DPU_cmin = min(app.DPU(:));
            app.DPU_cmax = max(app.DPU(:));
            
            % force display update
            app.display_data(app.data_currently_displayed);
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Coregistered DOD and DPU');
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_drawpoly
        function pb_drawpoly_Callback(app, event)
            
            % clear existing poly
            delete(app.ROI);
            app.ROI = [];
            
            % create polygon
            app.ROI = impoly;
            
            if ~isempty(app.ROI)
                % if impoly created
                
                % enable clear and clip
                set(app.pb_clearpoly,'Enable','on');
                set(app.pb_cliptopoly,'Enable','on');
                
                % get current position of vertices
                pos = getPosition(app.ROI);
                
                % update txt_out
                txt = get(app.txt_out,'Value');
                txt{end+1} = sprintf('- Drew new polygon with vertices (X,Y):');
                for ii = 1:size(pos,1)
                    txt{end+1} = sprintf('-- %f,%f',pos(ii,1),pos(ii,2));
                end
                set(app.txt_out,'Value',txt);
                
            end
            
        end

        % Button pushed function: pb_loadpoly
        function pb_loadpoly_Callback(app, event)
            
            if ~isempty(app.lastDataFolder)
                trythispath = app.lastDataFolder;
            else
                trythispath = app.RootFolder;
            end
            
            % prompt for file
            [file_name, path_name] = uigetfile({...
                '*.shp','Shapefiles (*.shp)'; ...
                '*.txt;*.csv','Coma-delimited coordinates files (*.txt;*.csv)'}, ...
                'select file for polygon',...
                trythispath);
            
            if isequal(file_name,0)
                msgbox('Please try again!','Error!','Modal');
                errordlg('File not found','File Error');
                return;
            end
            
            poly_file = [path_name file_name];
            
            if any( strcmp(poly_file(end-3:end), {'.txt','.csv'}) )
                pos = dlmread(poly_file);
            elseif strcmp(poly_file(end-3:end), '.shp')
                P = shaperead(poly_file); % needs mapping toolbox
                c = length(P); % number of polygons
                xpoly = cell(c,1); % preallocating
                ypoly = cell(c,1); % preallocating
                for ii= 1:c
                    xpoly{ii,1} = P(ii).X;
                    ypoly{ii,1} = P(ii).Y;
                end
                pos = [xpoly{1}',ypoly{1}'];
                pos(isnan(pos(:,1)),:)=[];
                pos(end,:)=[];
            end
            
            % clear existing poly
            delete(app.ROI);
            app.ROI = [];
            
            % create new poly
            app.ROI = impoly(gca,pos);
            setVerticesDraggable(app.ROI,'True')
            
            % enable clear and clip
            set(app.pb_clearpoly,'Enable','on');
            set(app.pb_cliptopoly,'Enable','on');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Loaded new polygon from file %s, with vertices (X,Y):',regexprep(poly_file,'\','\\'));
            for ii = 1:size(pos,1)
                txt{end+1} = sprintf('-- %f,%f',pos(ii,1),pos(ii,2));
            end
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_clearpoly
        function pb_clearpoly_Callback(app, event)
            
            % clear existing poly
            delete(app.ROI);
            app.ROI = [];
            
            % disable clear and clip
            set(app.pb_clearpoly,'Enable','Off');
            set(app.pb_cliptopoly,'Enable','Off');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Cleared polygon');
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_cliptopoly
        function pb_cliptopoly_Callback(app, event)
            
            % get poly position
            pos = getPosition(app.ROI);
            xv = pos(:,1);
            yv = pos(:,2);
            
            % clip rasters
            if ~isempty(app.DEM1)
                [app.DEM1,app.DEM1_X,app.DEM1_Y] = CFF_clip_raster(app.DEM1,app.DEM1_X,app.DEM1_Y,xv,yv);
                app.DEM1_cmin = min(app.DEM1(:));
                app.DEM1_cmax = max(app.DEM1(:));
            end
            if ~isempty(app.DEM2)
                [app.DEM2,app.DEM2_X,app.DEM2_Y] = CFF_clip_raster(app.DEM2,app.DEM2_X,app.DEM2_Y,xv,yv);
                app.DEM2_cmin = min(app.DEM2(:));
                app.DEM2_cmax = max(app.DEM2(:));
            end
            if ~isempty(app.DOD)
                [app.DOD,app.DOD_X,app.DOD_Y] = CFF_clip_raster(app.DOD,app.DOD_X,app.DOD_Y,xv,yv);
                app.DOD_cmin = min(app.DOD(:));
                app.DOD_cmax = max(app.DOD(:));
            end
            if ~isempty(app.UNC1)
                [app.UNC1,app.UNC1_X,app.UNC1_Y] = CFF_clip_raster(app.UNC1,app.UNC1_X,app.UNC1_Y,xv,yv);
                app.UNC1_cmin = min(app.UNC1(:));
                app.UNC1_cmax = max(app.UNC1(:));
            end
            if ~isempty(app.UNC2)
                [app.UNC2,app.UNC2_X,app.UNC2_Y] = CFF_clip_raster(app.UNC2,app.UNC2_X,app.UNC2_Y,xv,yv);
                app.UNC2_cmin = min(app.UNC2(:));
                app.UNC2_cmax = max(app.UNC2(:));
            end
            if ~isempty(app.DPU)
                [app.DPU,app.DPU_X,app.DPU_Y] = CFF_clip_raster(app.DPU,app.DPU_X,app.DPU_Y,xv,yv);
                app.DPU_cmin = min(app.DPU(:));
                app.DPU_cmax = max(app.DPU(:));
            end
            if ~isempty(app.LOD_X) % because if fixed, LOD exists but not LOD_x
                [app.LOD,app.LOD_X,app.LOD_Y] = CFF_clip_raster(app.LOD,app.LOD_X,app.LOD_Y,xv,yv);
                app.LOD_cmin = min(app.LOD(:));
                app.LOD_cmax = max(app.LOD(:));
            end
            
            % force display update
            app.display_data(app.data_currently_displayed);
            
            % clear existing poly
            delete(app.ROI);
            app.ROI = [];
            
            % disable clear and clip
            set(app.pb_clearpoly,'Enable','Off');
            set(app.pb_cliptopoly,'Enable','Off');
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Clipped all rasters to polygon with vertices (X,Y):');
            for ii = 1:size(pos,1)
                txt{end+1} = sprintf('-- %f,%f',pos(ii,1),pos(ii,2));
            end
            set(app.txt_out,'Value',txt);
            
        end

        % Value changed function: edt_mincol
        function edt_mincol_Callback(app, event)
            
            wanted_caxis = [str2num(app.edt_mincol.Value),str2num(app.edt_maxcol.Value)];
            caxis(wanted_caxis);
            
        end

        % Value changed function: edt_maxcol
        function edt_maxcol_Callback(app, event)
            
            wanted_caxis = [str2num(app.edt_mincol.Value),str2num(app.edt_maxcol.Value)];
            caxis(wanted_caxis);
            
        end

        % Button pushed function: pb_resetcolorscale
        function pb_resetcolorscale_Callback(app, event)
            
            % get whatever data is currently displayed
            whatM = app.data_currently_displayed;
            
            % get original caxis
            eval(sprintf('app.edt_mincol.Value = num2str(app.%s_cmin);',whatM));
            eval(sprintf('app.edt_maxcol.Value = num2str(app.%s_cmax);',whatM));
            
            % set caxis
            wanted_caxis = [str2num(app.edt_mincol.Value),str2num(app.edt_maxcol.Value)];
            caxis(wanted_caxis);
            
        end

        % Button pushed function: pb_calcLOD
        function pb_calcLOD_Callback(app, event)
            
            % grab data
            factor = str2num(app.edt_multFactor.Value);
            
            if app.rb1_fixedUNC.Value
                % calculate LOD with fixed uncertainty value
                
                % get that value
                UNC = str2num(app.edt_fixedUnc.Value);
                
                % calculate LOD
                app.LOD = factor.*UNC;
                
                % fixed value, no Xs and Ys:
                app.LOD_X = [];
                app.LOD_Y = [];
                
                % update text
                if app.LOD == 0
                    txt = sprintf('No LoD (LOD = 0m).');
                    set(app.txt_calcLOD,'Text',txt);
                elseif app.LOD>0
                    txt = sprintf('Fixed LoD = %g m.',app.LOD);
                    set(app.txt_calcLOD,'Text',txt);
                end
                
                % for fixed LOD value, no LOD to display
                set(app.pb_dispLOD,'Enable','off');
                
                % clear axis, in case a variable LOD was displayed
                if strcmp(app.data_currently_displayed,'LOD')
                    cla
                    
                    % disable stats button too
                    set(app.pb_compStats,'Enable','off');
                    
                    % clear and disable caxis edit boxes. Disable reset
                    % button
                    set(app.edt_mincol,'Value','','Enable','off');
                    set(app.edt_maxcol,'Value','','Enable','off');
                    set(app.pb_resetcolorscale,'Enable','off');
                    
                end
                
                % enable volume calculation if we have DOD and LOD
                if ~isempty(app.DOD) && ~isempty(app.LOD)
                    set(app.pb_analyzeChange,'Enable','on');
                end
                
                % update txt_out
                txt = get(app.txt_out,'Value');
                txt{end+1} = sprintf('- Calculated a fixed Limit of Detection (LOD)');
                txt{end+1} = sprintf('-- Fixed uncertainty UNC = ±%g m',UNC);
                txt{end+1} = sprintf('-- Multiplying factor k = %g',factor);
                txt{end+1} = sprintf('-- Fixed LoD = k*UNC = ±%g m',app.LOD);
                set(app.txt_out,'Value',txt);
                
            elseif app.rb2_varUNC.Value
                % % calculate LOD with variable uncertainty value
                
                % calculate LOD
                app.LOD = factor.*app.DPU;
                
                % other info
                app.LOD_X = app.DPU_X;
                app.LOD_Y = app.DPU_Y;
                app.LOD_cmin = min(app.LOD(:));
                app.LOD_cmax = max(app.LOD(:));
                
                % update text
                if factor == 0
                    txt = sprintf('No LoD (LOD = 0m).');
                    set(app.txt_calcLOD,'Text',txt);
                else
                    txt = sprintf('Spatially-variable LoD calculated.');
                    set(app.txt_calcLOD,'Text',txt);
                end
                
                % enable LOD display button
                set(app.pb_dispLOD,'Enable','on');
                
                % and display it
                app.display_data('LOD');
                
                % enable volume calculation if we have DOD and LOD
                if ~isempty(app.DOD) && ~isempty(app.LOD)
                    set(app.pb_analyzeChange,'Enable','on');
                end
                
                % update txt_out
                txt = get(app.txt_out,'Value');
                txt{end+1} = sprintf('- Calculated spatially-variable Limit of Detection (LOD)');
                txt{end+1} = sprintf('-- Using combined spatially-variable uncertainty DPU');
                txt{end+1} = sprintf('-- Multiplying factor k = %g',factor);
                txt{end+1} = sprintf('-- Spatially-variable LoD = k*DPU');
                set(app.txt_out,'Value',txt);
                
            else
                error('one of the two radiobuttons should be on')
            end
        end

        % Button pushed function: pb_dispLOD
        function pb_dispLOD_Callback(app, event)
            app.display_data('LOD');
        end

        % Button pushed function: pb_compStats
        function pb_compStats_Callback(app, event)
            
            % get whatever data is currently displayed
            whatM = app.data_currently_displayed;
            eval(sprintf('M = app.%s;',whatM));
            eval(sprintf('X = app.%s_X;',whatM));
            eval(sprintf('Y = app.%s_Y;',whatM));
            
            % if we have polygon, clip data to polygon so stats are only
            % for within polygon
            if ~isempty(app.ROI)
                
                % get poly position
                pos = getPosition(app.ROI);
                xv = pos(:,1);
                yv = pos(:,2);
                
                % clip raster
                [M,X,Y] = CFF_clip_raster(M,X,Y,xv,yv);
                
            end
            
            % calculate stats
            stat.min = min(M(:));
            stat.max = max(M(:));
            stat.mean = nanmean(M(:));
            stat.median = nanmedian(M(:));
            stat.std = nanstd(M(:));
            stat.perc6800      = CFF_invpercentile(abs(M(:)),68.00);
            stat.perc6827_1sig = CFF_invpercentile(abs(M(:)),68.27);
            stat.perc9545_2sig = CFF_invpercentile(abs(M(:)),95.45);
            stat.perc9900      = CFF_invpercentile(abs(M(:)),99.00);
            stat.perc9973_3sig = CFF_invpercentile(abs(M(:)),99.73);
            stat.perc9990      = CFF_invpercentile(abs(M(:)),99.90);
            stat.range = stat.max - stat.min;
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            if ~isempty(app.ROI)
                txt{end+1} = sprintf('- Calculated statistics for %s within polygon with vertices (X,Y):',whatM);
                for ii = 1:size(pos,1)
                    txt{end+1} = sprintf('-- %f,%f',pos(ii,1),pos(ii,2));
                end
                txt{end+1} = sprintf('-- Statistics results:');
            else
                txt{end+1} = sprintf('- Calculated statistics for %s on full current dataset. Results:',whatM);
            end
            txt{end+1} = sprintf('-- Minimum: %fm',stat.min);
            txt{end+1} = sprintf('-- Maximum: %fm',stat.max);
            txt{end+1} = sprintf('-- Mean: %fm',stat.mean);
            txt{end+1} = sprintf('-- Median: %fm',stat.median);
            txt{end+1} = sprintf('-- Stand dev: %fm',stat.std);
            txt{end+1} = sprintf('-- 68th percentile: %fm',stat.perc6800);
            txt{end+1} = sprintf('-- 68.27th percentile (1 sigma): %fm',stat.perc6827_1sig);
            txt{end+1} = sprintf('-- 95.45th percentile (2 sigmas): %fm',stat.perc9545_2sig);
            txt{end+1} = sprintf('-- 99th percentile: %fm',stat.perc9900);
            txt{end+1} = sprintf('-- 99.73th percentile (3 sigmas): %fm',stat.perc9973_3sig);
            txt{end+1} = sprintf('-- 99.9th percentile: %fm',stat.perc9990);
            txt{end+1} = sprintf('-- Range: %fm',stat.range);
            set(app.txt_out,'Value',txt);
            
        end

        % Button pushed function: pb_analyzeChange
        function pb_analyzeChange_Callback(app, event)
            
            % grab data
            DOD = app.DOD;
            X = app.DOD_X;
            Y = app.DOD_Y;
            LOD = app.LOD;
            if app.rb1_fixedUNC.Value
                UNC = str2num(app.edt_fixedUnc.Value);
            elseif app.rb2_varUNC.Value
                UNC = app.DPU;
            end
            
            % prepare txt_out update
            txt = get(app.txt_out,'Value');
            
            % if there's a polygon, clip the data further:
            if ~isempty(app.ROI)
                
                % prompt user
                msg = sprintf('This project includes a polygon.\nDo you want to run the Change Analysis on the area delimited by the polygon, or on the full current dataset?');
                title = 'Change Analysis';
                opt = {'Polygon Only','Full Current Dataset','Cancel'};
                defOpt = 1;
                choice = uiconfirm(gcf,msg,title,'Options',opt,'DefaultOption',defOpt,'CancelOption',3);
                
            else
                choice = 'Full Current Dataset';
            end
            
            % handle response
            switch choice
                case 'Polygon Only'
                    
                    % get poly position
                    pos = getPosition(app.ROI);
                    xv = pos(:,1);
                    yv = pos(:,2);
                    
                    % clip rasters
                    if size(app.LOD,1) > 1
                        LOD = CFF_clip_raster(LOD,X,Y,xv,yv);
                    end
                    if size(UNC,1) > 1
                        UNC = CFF_clip_raster(UNC,X,Y,xv,yv);
                    end
                    [DOD,X,Y] = CFF_clip_raster(DOD,X,Y,xv,yv);
                    
                    % prepare txt_out update
                    txt{end+1} = sprintf('- Analyzed change on area delimited by polygon with vertices (X,Y):');
                    for ii = 1:size(pos,1)
                        txt{end+1} = sprintf('-- %f,%f',pos(ii,1),pos(ii,2));
                    end
                    txt{end+1} = sprintf('-- Analysis results:');
                    
                case 'Full Current Dataset'
                    
                    % prepare txt_out update
                    txt{end+1} = sprintf('- Analyzed change on full current dataset. Results:');
                    
                case 'Cancel'
                    return
            end
            
            % waitbar
            title = 'Change Analysis';
            msg = sprintf('Running Change Analysis on %s. Please wait.',lower(choice));
            d = uiprogressdlg(gcf,'Title',title,'Message',msg,...
                'Indeterminate','on','Cancelable','on');
            
            % volume calculations:
            volumes = CFF_LOD_volumes(DOD,X,Y,LOD,UNC);
            
            % some more update txt_out
            txt{end+1} = sprintf('-- Total area considered: %g m^2',volumes.areaTotal);
            txt{end+1} = sprintf('-- Total area experiencing change (deposition or erosion above LOD): %g m^2 (~%.2f%% of total area)',volumes.areaTotalChange, volumes.areaTotalChange.*100./volumes.areaTotal);
            
            txt{end+1} = sprintf('-- Area experiencing erosion above LOD: %g m^2 (~%.2f%% of total area)',                             volumes.areaEroded,      volumes.areaEroded.*100./volumes.areaTotal);
            txt{end+1} = sprintf('-- Volume eroded: %g m^3',volumes.volumeEroded);
            txt{end+1} = sprintf('-- Uncertainty for volume eroded (propagated in sum): ±%g m^3',volumes.uncertaintyVolumeEroded_sum);
            txt{end+1} = sprintf('-- Uncertainty for volume eroded (propagated in quadrature): ±%g m^3',volumes.uncertaintyVolumeEroded_propagated);
            
            txt{end+1} = sprintf('-- Area experiencing deposition above LOD: %g m^2 (~%.2f%% of total area)',                          volumes.areaDeposited,   volumes.areaDeposited.*100./volumes.areaTotal);
            txt{end+1} = sprintf('-- Volume deposited: %g m^3',volumes.volumeDeposited);
            txt{end+1} = sprintf('-- Uncertainty for volume deposited (propagated in sum): ±%g m^3',volumes.uncertaintyVolumeDeposited_sum);
            txt{end+1} = sprintf('-- Uncertainty for volume deposited (propagated in quadrature): ±%g m^3',volumes.uncertaintyVolumeDeposited_propagated);
            
            txt{end+1} = sprintf('-- Volume net change (net deposition if positive): %g m^3',volumes.volumeNetChange);
            
            set(app.txt_out,'Value',txt);
            
            % close waitbar
            close(d);
            
            
        end

        % Button pushed function: pb_exportEcho
        function pb_exportEcho_Callback(app, event)
            
            % default file
            default_filename = sprintf('%s_Grounds_echo.txt',datestr(now,'yyyymmdd-HHMMSS'));
            default_file = fullfile(app.UserFolder,default_filename);
            
            % dialog box to prompt saving
            [file_name,path_name] = uiputfile('*.txt','Save file name',default_file);
            
            if isequal(file_name,0)
                msgbox('Please try again!','Error!','Modal');
                errordlg('File not found','File Error');
                return;
            end
            
            file = fullfile(path_name,file_name);
            
            % update txt_out
            txt = get(app.txt_out,'Value');
            txt{end+1} = sprintf('- Exported echo to %s',file);
            set(app.txt_out,'Value',txt);
            
            % save data in file
            fid = fopen(file,'wt');
            fprintf(fid,'%s\n',txt{:});
            fclose(fid);
            
        end

        % Button pushed function: pb_reset
        function pb_reset_Callback(app, event)
            % close app
            close(gcbf);
            % restart app
            Grounds;
        end

        % Button pushed function: pb_exit
        function pb_exit_Callback(app, event)
            % close app
            close(gcbf);
        end

        % Off callback: menu_lockaxis
        function menu_lockaxis_OffCallback(app, event)
            % axis back to default
            axis auto
            axis equal
            daspect([1 1 1])
            app.axisLims = [];
        end

        % On callback: menu_lockaxis
        function menu_lockaxis_OnCallback(app, event)
            % save current axis
            app.axisLims = axis;
        end

        % Clicked callback: menu_help
        function menu_help_ClickedCallback(app, event)
          
            p = get( groot, 'Screensize' );
            
            d = dialog('Position',[300 300 0.5.*p(3) 0.8.*p(4)],'WindowStyle','normal','Resize','Off','Name','Elevation Change Analysis Help');
            
            movegui(d,'center');
            
            btn = uicontrol('Parent',d,...
                'Position',[0.25.*p(3)-35 5 70 25],...
                'String','Close',...
                'Callback','delete(gcf)');
            
            lbox = uicontrol('Parent',d,...
                'Style','listbox',...
                'Position',[10 40 0.5.*p(3)-20 0.8.*p(4)-50]);
            
            helptxt = sprintf( ['------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------', ...
                '\n------------------------------------------------------------------------------------------ ELEVATION CHANGE ANALYSIS -------------------------------------------------------------------------------------------', ...
                '\n------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------', ...
                '\nA tool to measure the change in area and volume between consecutive topographic or bathymetric datasets, including estimates of uncertainty.', ...
                '\n', ...
                '\nAUTHORS:', ...
                '\n- Alexandre SCHIMEL (NIWA, Deakin University) alex.schimel@niwa.co.nz', ...
                '\n- Rozaimi CHE HASAN (Universiti Teknologi Malaysia, Deakin University)', ...
                '\n', ...
                '\nIMPORTANT NOTES:', ...
                '\n- The use of the polygon tool requires the Image Processing Toolbox, but everything else should work without it.', ...
                '\n- This tool was built to implement the methodology described in: Schimel ACG, Ierodiaconou D, Hulands L, Kennedy DM (2015) Accounting for uncertainty in volumes of seabed change', ...
                '\nmeasured with repeat multibeam sonar surveys. Cont Shelf Res. doi: 10.1016/j.csr.2015.10.019.', ...
                '\n- This tool makes use of individual functions found at: https://github.com/alexschimel/CoFFee/tree/master/functions/gis_DEM_analysis', ...
                '\n- The "Reset" button will reset the entire tool to start fresh, while the "Exit" button will close it.', ...
                '\n', ...
                '\nBASIC USE:', ...
                '\n- Open two DEMs using the "Open DEM 1" and "Open DEM 2" buttons. Data file formats accepted are grid ASCII and TIFF.', ...
                '\n- Switch between datasets using the appropriate "Display" button.', ...
                '\n- The two DEMs must be co-registered to allow computing the difference (DOD = DEM2 - DEM1), so use the "Coregister DEMs" and "Calculate DOD" buttons in that order.', ...
                '\n- You can adjust the color scale of any dataset being displayed using the fields in the "Color Scale" panel. The button "Reset" will reset that color scale to the full range, but for the', ...
                '\ndisplayed dataset only.', ...
                '\n- You can zoom in and out or drag around the data using the appropriate icons in the toolbar. The "Lock" icon in the toolbar allows maintaining the boundaries of the current view when', ...
                '\nswitching the display between datasets.', ...
                '\n- Click the "Compute Stats" button to compute basic statistics about the dataset being displayed.', ...
                '\n- The "Echo" panel displays all actions taken and results. At any point, you can click "Export echo" to export the contents of the window in a text file.', ...
                '\n', ...
                '\nPOLYGON:', ...
                '\n- The "Draw" button allows manually drawing a polygon over the data displayed. Use the mouse''s left button to add a vertex and double-click to finish.', ...
                '\n- The "Load" button allows inputting a file containing a polygon''s vertices. Delimited text files and shapefiles are currently accepted. Make sure the coordinate systems are the same.', ...
                '\n- Any polygon can be interacted with, that is, you can drag around indiviudal vertices, or dragging the entire polygon.' , ...
                '\n- The "Clear" button will clear the current polygon.', ...
                '\n- The "Clip to" button will clip all loaded datasets to the polygon. Useful to limit the size of the datasets to the relevant overlap area, and help speed up further calculations.', ...
                '\n- When a polygon is in, the "Compute Stats" button will apply calculations only to the data within the polygon.', ...
                '\n', ...
                '\nSTATS:', ...
                '\n- Ontop of basic statistics (mean, median, standard deviation, minimum, maximum, range), the tool displays percentiles, that is, the value under which a certain percentage of values fall.', ...
                '\n- For example, 68.72th percentile is the value under which, 68.72%% of all values fall.. which is equal to 1 standard deviation if the data were normally distributed.', ...
                '\n- Stats allow you to figure out, among others, an estimate of variation in a DOD over an area that has not experienced change, which can be used as a fixed uncertainty estimate.', ...
                '\n', ...
                '\nFIXED UNCERTAINTY:', ...
                '\n- Uncertainty is used in two manners in this tool: To provide uncertainty intervals for volumes, and to calculate a "Limit of Detection", aka a bound for any change to be considered in', ...
                '\ncalculations.', ...
                '\n- Say you set a fixed uncertainty value of U=±1m and a multiplying factor of k=2, then a LoD of k*U=±2m will be used, that is, only changes in height above 2m or below -2m will be', ....
                '\nconsidered in volume calculations. However, all volumetric estimates will keep using U=±1m as the basic uncertainty to be propagated to volumetric uncertainty calculations.', ...
                '\n- Setting a factor of k=0 allows you to compute volumes with uncertainty intervals but without using a Limit of Detection.', ...
                '\n- You can do all the above by setting the appropriate values in the "Limit of Detection" panel, then clicking "Calculate LoD".', ...
                '\n- Once a LoD has been calculated (Even one with LOD=0 if the factor was set to 0), it is possible to compute volumes using the "Analyze Change" button.', ...
                '\n- Note that if a polygon is in, a window will prompt you whether you want the change analysis to be calculated on the data within the polygon only, or the full dataset', ...
                '\n', ...
                '\nSPATIALLY VARIABLE UNCERTAINTY:', ...
                '\n- If you have spatially variable uncertainty rasters for each DEM, these can be loaded using the "Open UNC1" and Open UNC2" buttons', ...
                '\n- Like DEMs, these need to be coregistered before the combined uncertainty (DPU) can be calculated. So use the "Coregister UNCs" and "Calculate DPU" in that order.', ...
                '\n- DOD and DPU must be coregistered in order to use DPU as a source of uncertainty. Use the "Coregister DOD and DPU" accordingly.', ...
                '\n- Once DOD and DPU exist and are coregistered, it is possible to calculate a spatially variable Limit of Detection, using the radio button "Variable uncertainty (Use DPU)".', ...
                '\n- A spatially variable LOD can be displayed, and interacted with, just like all other datasets.', ...
                '\n', ...
                '\nCHANGE ANALYSIS:', ...
                '\n- A Change Analysis includes the following calculations:', ...
                '\n     - Total area considered: The total area in m^2 within the polygon or full dataset.', ...
                '\n     - Total area experiencing change: The total area experiencing a deposition or erosion above the Limit of Detection. Also written as a percentage of total area considered.', ...
                '\n     - Area experiencing erosion above LOD: The total area experiencing erosion above the Limit of Detection. Also written as a percentage of total area considered.', ...
                '\n     - Volume eroded: The total volume eroded, considering only variation above the Limit of Detection.', ...
                '\n     - Uncertainty for volume eroded (propagated in sum): The uncertainty interval for volume eroded, calculated by propagating uncertainty in sum over area of erosion.', ...
                '\n     - Uncertainty for volume eroded (propagated in quadrature): The uncertainty interval for volume eroded, calculated by propagating uncertainty in quadrature over area of erosion.', ...
                '\n     - Area experiencing deposition above LOD: The total area experiencing deposition above the Limit of Detection. Also written as a percentage of total area considered.', ...
                '\n     - Volume deposited: The total volume deposited, considering only variation above the Limit of Detection.', ...
                '\n     - Uncertainty for volume deposited (propagated in sum): The uncertainty interval for volume deposited, calculated by propagating uncertainty in sum over area of deposition.', ...
                '\n     - Uncertainty for volume deposited (propagated in quadrature): The uncertainty interval for volume deposited, calculated by propagating uncertainty in quadrature over area of deposition.', ...
                '\n     - Volume net change (net deposition if positive): Difference between volume eroded and volume deposited. Indicates a net deposition if positive and a net erosion if negative.', ...
                ''] );
            
            set(lbox,'String',helptxt);
        end

        % Clicked callback: menu_tooltip
        function menu_tooltip_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
            
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.menu_tooltip.State;
            datacursormode(app.MainFigure, char(state));
        end

        % Clicked callback: menu_zoomout
        function menu_zoomout_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
            
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.menu_zoomout.State;
            zoomModeObject = zoom(app.MainFigure);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: menu_zoomin
        function menu_zoomin_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
            
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.menu_zoomin.State;
            zoomModeObject = zoom(app.MainFigure);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: menu_pan
        function menu_pan_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
            
            % Enable or disable pan based on the
            % tool's current state.
            state = app.menu_pan.State;
            pan(app.MainFigure, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Grounds';
            app.MainFigure.Tag = 'figure1';
            app.menu = uitoolbar(app.MainFigure);
            app.menu.Tag = 'uitoolbar1';
            app.menu_pan = uitoggletool(app.menu);
            app.menu_pan.Tag = 'uitoggletool5';
            app.menu_pan.Tooltip = 'Pan';
            app.menu_pan.ClickedCallback = createCallbackFcn(app, @menu_pan_ClickedCallback, true);
            app.menu_pan.Icon = 'uitoggletool5_image.png';
            app.menu_zoomin = uitoggletool(app.menu);
            app.menu_zoomin.Tag = 'uitoggletool1';
            app.menu_zoomin.Tooltip = 'Zoom In';
            app.menu_zoomin.ClickedCallback = createCallbackFcn(app, @menu_zoomin_ClickedCallback, true);
            app.menu_zoomin.Icon = 'uitoggletool1_image.png';
            app.menu_zoomout = uitoggletool(app.menu);
            app.menu_zoomout.Tag = 'uitoggletool2';
            app.menu_zoomout.Tooltip = 'Zoom Out';
            app.menu_zoomout.ClickedCallback = createCallbackFcn(app, @menu_zoomout_ClickedCallback, true);
            app.menu_zoomout.Icon = 'uitoggletool2_image.png';
            app.menu_lockaxis = uitoggletool(app.menu);
            app.menu_lockaxis.Tag = 'togg_lock_axis';
            app.menu_lockaxis.Tooltip = 'Lock/Unlock axis';
            app.menu_lockaxis.Icon = 'togg_lock_axis_image.png';
            app.menu_lockaxis.OffCallback = createCallbackFcn(app, @menu_lockaxis_OffCallback, true);
            app.menu_lockaxis.OnCallback = createCallbackFcn(app, @menu_lockaxis_OnCallback, true);
            app.menu_tooltip = uitoggletool(app.menu);
            app.menu_tooltip.Tag = 'uitoggletool3';
            app.menu_tooltip.Tooltip = 'Data Cursor';
            app.menu_tooltip.ClickedCallback = createCallbackFcn(app, @menu_tooltip_ClickedCallback, true);
            app.menu_tooltip.Icon = 'uitoggletool3_image.png';
            app.menu_help = uipushtool(app.menu);
            app.menu_help.Tag = 'uipush_help';
            app.menu_help.ClickedCallback = createCallbackFcn(app, @menu_help_ClickedCallback, true);
            app.menu_help.Icon = 'uipush_help_image.png';
            app.panel_echo = uipanel(app.MainFigure);
            app.panel_echo.Title = 'Echo';
            app.panel_echo.Tag = 'uipanel6';
            app.Label = uilabel(app.panel_echo);
            app.Label.Text = '';
            app.txt_out = uitextarea(app.panel_echo);
            app.txt_out.Editable = 'off';
            app.panel_actions = uipanel(app.MainFigure);
            app.pb_exit = uibutton(app.panel_actions, 'push');
            app.pb_exit.ButtonPushedFcn = createCallbackFcn(app, @pb_exit_Callback, true);
            app.pb_exit.Tag = 'pb_exit';
            app.pb_exit.Text = 'Exit';
            app.pb_reset = uibutton(app.panel_actions, 'push');
            app.pb_reset.ButtonPushedFcn = createCallbackFcn(app, @pb_reset_Callback, true);
            app.pb_reset.Tag = 'pb_reset';
            app.pb_reset.Text = 'Reset';
            app.pb_exportEcho = uibutton(app.panel_actions, 'push');
            app.pb_exportEcho.ButtonPushedFcn = createCallbackFcn(app, @pb_exportEcho_Callback, true);
            app.pb_exportEcho.Tag = 'pb_export_echo';
            app.pb_exportEcho.Text = 'Export echo';
            app.pb_analyzeChange = uibutton(app.panel_actions, 'push');
            app.pb_analyzeChange.ButtonPushedFcn = createCallbackFcn(app, @pb_analyzeChange_Callback, true);
            app.pb_analyzeChange.Tag = 'pb_vol';
            app.pb_analyzeChange.Enable = 'off';
            app.pb_analyzeChange.Text = 'Analyze Change';
            app.pb_compStats = uibutton(app.panel_actions, 'push');
            app.pb_compStats.ButtonPushedFcn = createCallbackFcn(app, @pb_compStats_Callback, true);
            app.pb_compStats.Tag = 'pb_stats';
            app.pb_compStats.Enable = 'off';
            app.pb_compStats.Text = 'Compute Stats';
            app.panel_LOD = uipanel(app.MainFigure);
            app.panel_LOD.Title = 'Limit of Detection';
            app.panel_LOD.Tag = 'uipanel13';
            app.pb_dispLOD = uibutton(app.panel_LOD, 'push');
            app.pb_dispLOD.ButtonPushedFcn = createCallbackFcn(app, @pb_dispLOD_Callback, true);
            app.pb_dispLOD.Tag = 'pb_display_LOD';
            app.pb_dispLOD.Enable = 'off';
            app.pb_dispLOD.Text = 'Display';
            app.txt_calcLOD = uilabel(app.panel_LOD);
            app.txt_calcLOD.Tag = 'txt_LOD';
            app.txt_calcLOD.Enable = 'off';
            app.txt_calcLOD.Text = 'LoD not calculated yet';
            app.pb_calcLOD = uibutton(app.panel_LOD, 'push');
            app.pb_calcLOD.ButtonPushedFcn = createCallbackFcn(app, @pb_calcLOD_Callback, true);
            app.pb_calcLOD.Tag = 'pb_calculate_LOD';
            app.pb_calcLOD.Enable = 'off';
            app.pb_calcLOD.Text = 'Calculate LoD';
            app.edt_multFactor = uieditfield(app.panel_LOD, 'text');
            app.edt_multFactor.Tag = 'edt_multiplying_factor';
            app.edt_multFactor.Enable = 'off';
            app.edt_multFactor.Value = '1';
            app.txt_multFactor = uilabel(app.panel_LOD);
            app.txt_multFactor.Tag = 'txt_multiplying_factor';
            app.txt_multFactor.Enable = 'off';
            app.txt_multFactor.Text = 'Multiplying factor:';
            app.rbg_unctype = uibuttongroup(app.panel_LOD);
            app.rb2_varUNC = uiradiobutton(app.rbg_unctype);
            app.rb2_varUNC.Enable = 'off';
            app.rb2_varUNC.Text = 'Variable uncertainty (use DPU)';
            app.rb1_fixedUNC = uiradiobutton(app.rbg_unctype);
            app.rb1_fixedUNC.Enable = 'off';
            app.rb1_fixedUNC.Text = 'Fixed uncertainty (±m)';
            app.rb1_fixedUNC.Value = true;
            app.edt_fixedUnc = uieditfield(app.rbg_unctype, 'text');
            app.edt_fixedUnc.Tag = 'edt_fixed_uncertainty';
            app.edt_fixedUnc.Enable = 'off';
            app.edt_fixedUnc.Value = '0';
            app.panel_colorscale = uipanel(app.MainFigure);
            app.panel_colorscale.Title = 'Color Scale';
            app.panel_colorscale.Tag = 'uipanel14';
            app.pb_resetcolorscale = uibutton(app.panel_colorscale, 'push');
            app.pb_resetcolorscale.ButtonPushedFcn = createCallbackFcn(app, @pb_resetcolorscale_Callback, true);
            app.pb_resetcolorscale.Tag = 'pb_reset_colorbar';
            app.pb_resetcolorscale.Enable = 'off';
            app.pb_resetcolorscale.Text = 'Reset';
            app.edt_maxcol = uieditfield(app.panel_colorscale, 'text');
            app.edt_maxcol.ValueChangedFcn = createCallbackFcn(app, @edt_maxcol_Callback, true);
            app.edt_maxcol.Tag = 'edt_max';
            app.edt_maxcol.Enable = 'off';
            app.text_maxcol = uilabel(app.panel_colorscale);
            app.text_maxcol.Tag = 'text41';
            app.text_maxcol.Text = 'Max:';
            app.edt_mincol = uieditfield(app.panel_colorscale, 'text');
            app.edt_mincol.ValueChangedFcn = createCallbackFcn(app, @edt_mincol_Callback, true);
            app.edt_mincol.Tag = 'edt_min';
            app.edt_mincol.Enable = 'off';
            app.text_mincol = uilabel(app.panel_colorscale);
            app.text_mincol.Tag = 'text40';
            app.text_mincol.Text = 'Min:';
            app.panel_polygon = uibuttongroup(app.MainFigure);
            app.panel_polygon.Title = 'Polygon';
            app.panel_polygon.Tag = 'uibuttongroup2';
            app.pb_cliptopoly = uibutton(app.panel_polygon, 'push');
            app.pb_cliptopoly.ButtonPushedFcn = createCallbackFcn(app, @pb_cliptopoly_Callback, true);
            app.pb_cliptopoly.Tag = 'pb_clip_rasters_to_poly';
            app.pb_cliptopoly.Enable = 'off';
            app.pb_cliptopoly.Text = 'Clip to';
            app.pb_clearpoly = uibutton(app.panel_polygon, 'push');
            app.pb_clearpoly.ButtonPushedFcn = createCallbackFcn(app, @pb_clearpoly_Callback, true);
            app.pb_clearpoly.Tag = 'pb_clear_poly';
            app.pb_clearpoly.Enable = 'off';
            app.pb_clearpoly.Text = 'Clear';
            app.pb_loadpoly = uibutton(app.panel_polygon, 'push');
            app.pb_loadpoly.ButtonPushedFcn = createCallbackFcn(app, @pb_loadpoly_Callback, true);
            app.pb_loadpoly.Tag = 'pb_select_poly';
            app.pb_loadpoly.Enable = 'off';
            app.pb_loadpoly.Text = 'Load';
            app.pb_drawpoly = uibutton(app.panel_polygon, 'push');
            app.pb_drawpoly.ButtonPushedFcn = createCallbackFcn(app, @pb_drawpoly_Callback, true);
            app.pb_drawpoly.Tag = 'pb_drawpoly';
            app.pb_drawpoly.Enable = 'off';
            app.pb_drawpoly.Text = 'Draw';
            app.panel_inputUNC = uipanel(app.MainFigure);
            app.panel_inputUNC.Title = 'Input Uncertainty';
            app.panel_inputUNC.Tag = 'uipanel3';
            app.pb_coregister_DOD_and_DPU = uibutton(app.panel_inputUNC, 'push');
            app.pb_coregister_DOD_and_DPU.ButtonPushedFcn = createCallbackFcn(app, @pb_coregister_DOD_and_DPU_Callback, true);
            app.pb_coregister_DOD_and_DPU.Tag = 'pb_coregister_DOD_and_DPU';
            app.pb_coregister_DOD_and_DPU.Enable = 'off';
            app.pb_coregister_DOD_and_DPU.Text = 'Coregister DOD and DPU';
            app.pb_display_DPU = uibutton(app.panel_inputUNC, 'push');
            app.pb_display_DPU.ButtonPushedFcn = createCallbackFcn(app, @pb_display_DPU_Callback, true);
            app.pb_display_DPU.Tag = 'pb_display_DPU';
            app.pb_display_DPU.Enable = 'off';
            app.pb_display_DPU.Text = 'Display';
            app.pb_calculate_DPU = uibutton(app.panel_inputUNC, 'push');
            app.pb_calculate_DPU.ButtonPushedFcn = createCallbackFcn(app, @pb_calculate_DPU_Callback, true);
            app.pb_calculate_DPU.Tag = 'pb_calculate_DPU';
            app.pb_calculate_DPU.Enable = 'off';
            app.pb_calculate_DPU.Text = 'Calculate DPU';
            app.pb_coregister_UNCs = uibutton(app.panel_inputUNC, 'push');
            app.pb_coregister_UNCs.ButtonPushedFcn = createCallbackFcn(app, @pb_coregister_UNCs_Callback, true);
            app.pb_coregister_UNCs.Tag = 'pb_coregister_UNCs';
            app.pb_coregister_UNCs.Enable = 'off';
            app.pb_coregister_UNCs.Text = 'Coregister UNCs';
            app.pb_display_UNC2 = uibutton(app.panel_inputUNC, 'push');
            app.pb_display_UNC2.ButtonPushedFcn = createCallbackFcn(app, @pb_display_UNC2_Callback, true);
            app.pb_display_UNC2.Tag = 'pb_display_UNC2';
            app.pb_display_UNC2.Enable = 'off';
            app.pb_display_UNC2.Text = 'Display';
            app.text_UNC2 = uilabel(app.panel_inputUNC);
            app.text_UNC2.Tag = 'text_UNC2';
            app.text_UNC2.Enable = 'off';
            app.text_UNC2.Text = 'UNC 2 file';
            app.pb_openUN2 = uibutton(app.panel_inputUNC, 'push');
            app.pb_openUN2.ButtonPushedFcn = createCallbackFcn(app, @pb_openUN2_Callback, true);
            app.pb_openUN2.Tag = 'pb_openUN2';
            app.pb_openUN2.Text = 'Open UNC 2';
            app.pb_display_UNC1 = uibutton(app.panel_inputUNC, 'push');
            app.pb_display_UNC1.ButtonPushedFcn = createCallbackFcn(app, @pb_display_UNC1_Callback, true);
            app.pb_display_UNC1.Tag = 'pb_display_UNC1';
            app.pb_display_UNC1.Enable = 'off';
            app.pb_display_UNC1.Text = 'Display';
            app.text_UNC1 = uilabel(app.panel_inputUNC);
            app.text_UNC1.Tag = 'text_UNC1';
            app.text_UNC1.Enable = 'off';
            app.text_UNC1.Text = 'UNC 1 file';
            app.pb_openUN1 = uibutton(app.panel_inputUNC, 'push');
            app.pb_openUN1.ButtonPushedFcn = createCallbackFcn(app, @pb_openUN1_Callback, true);
            app.pb_openUN1.Tag = 'pb_openUN1';
            app.pb_openUN1.Text = 'Open UNC 1';
            app.panel_inputDEM = uipanel(app.MainFigure);
            app.panel_inputDEM.Title = 'Input DEM';
            app.panel_inputDEM.Tag = 'uipanel2';
            app.pb_display_DOD = uibutton(app.panel_inputDEM, 'push');
            app.pb_display_DOD.ButtonPushedFcn = createCallbackFcn(app, @pb_display_DOD_Callback, true);
            app.pb_display_DOD.Tag = 'pb_display_DOD';
            app.pb_display_DOD.Enable = 'off';
            app.pb_display_DOD.Text = 'Display';
            app.pb_calculate_DOD = uibutton(app.panel_inputDEM, 'push');
            app.pb_calculate_DOD.ButtonPushedFcn = createCallbackFcn(app, @pb_calculate_DOD_Callback, true);
            app.pb_calculate_DOD.Tag = 'pb_calculate_DOD';
            app.pb_calculate_DOD.Enable = 'off';
            app.pb_calculate_DOD.Text = 'Calculate DOD';
            app.pb_coregister_DEMs = uibutton(app.panel_inputDEM, 'push');
            app.pb_coregister_DEMs.ButtonPushedFcn = createCallbackFcn(app, @pb_coregister_DEMs_Callback, true);
            app.pb_coregister_DEMs.Tag = 'pb_coregister_DEMs';
            app.pb_coregister_DEMs.Enable = 'off';
            app.pb_coregister_DEMs.Text = 'Coregister DEMs';
            app.pb_display_DEM2 = uibutton(app.panel_inputDEM, 'push');
            app.pb_display_DEM2.ButtonPushedFcn = createCallbackFcn(app, @pb_display_DEM2_Callback, true);
            app.pb_display_DEM2.Tag = 'pb_display_DEM2';
            app.pb_display_DEM2.Enable = 'off';
            app.pb_display_DEM2.Text = 'Display';
            app.text_DEM2 = uilabel(app.panel_inputDEM);
            app.text_DEM2.Tag = 'text_DEM2';
            app.text_DEM2.Enable = 'off';
            app.text_DEM2.Text = 'DEM 2 file';
            app.pb_openDEM2 = uibutton(app.panel_inputDEM, 'push');
            app.pb_openDEM2.ButtonPushedFcn = createCallbackFcn(app, @pb_openDEM2_Callback, true);
            app.pb_openDEM2.Tag = 'pb_openDEM2';
            app.pb_openDEM2.Text = 'Open DEM 2';
            app.pb_display_DEM1 = uibutton(app.panel_inputDEM, 'push');
            app.pb_display_DEM1.ButtonPushedFcn = createCallbackFcn(app, @pb_display_DEM1_Callback, true);
            app.pb_display_DEM1.Tag = 'pb_display_DEM1';
            app.pb_display_DEM1.Enable = 'off';
            app.pb_display_DEM1.Text = 'Display';
            app.text_DEM1 = uilabel(app.panel_inputDEM);
            app.text_DEM1.Tag = 'text_DEM1';
            app.text_DEM1.Enable = 'off';
            app.text_DEM1.Text = 'DEM 1 file';
            app.pb_openDEM1 = uibutton(app.panel_inputDEM, 'push');
            app.pb_openDEM1.ButtonPushedFcn = createCallbackFcn(app, @pb_openDEM1_Callback, true);
            app.pb_openDEM1.Tag = 'pb_openDEM1';
            app.pb_openDEM1.Tooltip = 'Open DEM to display';
            app.pb_openDEM1.Text = 'Open DEM 1';
            app.main_axes = uiaxes(app.MainFigure);
            app.main_axes.Tag = 'main_axes';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Grounds(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end